classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SeuilEditField                  matlab.ui.control.NumericEditField
        SeuilEditFieldLabel             matlab.ui.control.Label
        StatistiquedelimageTextArea     matlab.ui.control.TextArea
        StatistiquedelimageTextAreaLabel  matlab.ui.control.Label
        imagetransformeLabel            matlab.ui.control.Label
        imageoriginaleLabel             matlab.ui.control.Label
        AppliquerButton                 matlab.ui.control.Button
        SlectionnezvotrefiltreDropDown  matlab.ui.control.DropDown
        SlectionnezvotrefiltreDropDownLabel  matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageData = [];   
        seuilValue = 0 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ImageClicked(app, event)

           
        end

        % Button pushed function: AppliquerButton
        function AppliquerButtonPushed(app, event)
     % Vérifier qu'une image est chargée
if isempty(app.ImageData)
    msgbox("Veuillez d'abord charger une image.", 'Erreur');
    return;
end

filtre = app.SlectionnezvotrefiltreDropDown.Value;

if size(app.ImageData, 3) == 3
    ImageGray = rgb2gray(app.ImageData);
else
    ImageGray = app.ImageData;
end

imgDouble = im2double(ImageGray);

% Récupérer la valeur du seuil
seuilVal = app.seuilValue; 


switch filtre
    case 'Gamma correction'
        gamma = 0.5;
        imgResult = im2uint8(imgDouble .^ gamma);

    case 'Transformation exponentielle'
        imgExp = exp(imgDouble) - 1;
        imgResult = im2uint8(imgExp / max(imgExp(:)));

    case 'Transformation logarithmique'
        imgLog = log(1 + imgDouble);
        imgResult = im2uint8(imgLog / max(imgLog(:)));

    case 'Étirement linéaire'
        minVal = double(min(ImageGray(:)));
        maxVal = double(max(ImageGray(:)));
        imgResult = uint8(255 * (double(ImageGray) - minVal) / (maxVal - minVal));

    case 'Égalisation d''histogramme'
        imgResult = histeq(ImageGray);

    case 'Filtre Sobel'
        Gx = [-1 0 1; -2 0 2; -1 0 1];
        Gy = [-1 -2 -1;  0  0  0;  1  2  1];
        gradX = imfilter(double(ImageGray), Gx);
        gradY = imfilter(double(ImageGray), Gy);
        magnitude = sqrt(gradX.^2 + gradY.^2);
        imgResult = uint8(255 * magnitude / max(magnitude(:)));

    case 'Méthode OTSU'
        if app.seuilValue == 0
            % Auto OTSU
            seuil = graythresh(ImageGray);
        else
           
            seuil = app.seuilValue / 255;
        end
        imgResult = uint8(imbinarize(ImageGray, seuil)) * 255;
end

% ---- AFFICHER L'IMAGE TRANSFORMÉE ----
app.Image_2.ImageSource = '';
drawnow;
tempPath = fullfile(tempdir, ['img_transformee_' char(datetime('now','Format','HHmmssSSS')) '.png']);
imwrite(imgResult, tempPath);
app.Image_2.ImageSource = tempPath;

% ---- METTRE À JOUR L'HISTOGRAMME ----
cla(app.UIAxes);
[counts, bins] = imhist(imgResult);
bar(app.UIAxes, bins, counts);
title(app.UIAxes, ['Histogramme - ' filtre]);
xlabel(app.UIAxes, 'Intensité');
ylabel(app.UIAxes, 'Nombre de pixels');
xlim(app.UIAxes, [0 255]);

% ---- STATISTIQUES ----
moyenneVal = mean2(double(imgResult));
ecartType  = std2(double(imgResult));
valMin     = double(min(imgResult(:)));
valMax     = double(max(imgResult(:)));
medianeVal = double(median(imgResult(:)));

stats = {
    ['Filtre     : ' filtre];
    ['Moyenne    : ' num2str(moyenneVal, '%.2f')];
    ['Écart-type : ' num2str(ecartType, '%.2f')];
    ['Min        : ' num2str(valMin)];
    ['Max        : ' num2str(valMax)];
    ['Médiane    : ' num2str(medianeVal)]
};

if strcmp(filtre, 'Méthode OTSU')
    stats{end+1} = ['Seuil OTSU : ' num2str(round(seuil * 255))];
end

app.StatistiquedelimageTextArea.Value = stats;
        end

        % Callback function
        function Transforme(app, event)
            
        end

        % Callback function
        function ImageClicked2(app, event)
            
        end

        % Image clicked function: Image
        function ChargerImageButtonPushed(app, event)
    [file, path] = uigetfile({'*.jpg;*.png;*.jpeg'});
 
    filepath = fullfile(path, file);
    app.ImageData = imread(filepath);
    app.Image.ImageSource = app.ImageData;
    
   
    % Afficher l'histogramme
    [counts, bins] = imhist(app.ImageData);
    bar(app.UIAxes, bins, counts);
    title(app.UIAxes, 'Histogramme d''image');
    xlabel(app.UIAxes, 'Intensité');
    ylabel(app.UIAxes, 'Nombre de pixels');
    xlim(app.UIAxes, [0 255]);
    ylim(app.UIAxes, [0 max(counts)+100]);

    % Afficher les statistiques
    moyenneVal = mean2(app.ImageData);
    ecartType  = std2(app.ImageData);
    valMin     = double(min(app.ImageData(:)));
    valMax     = double(max(app.ImageData(:)));
    medianeVal = double(median(app.ImageData(:)));
    
    stats = {
        ['Moyenne    : ' num2str(moyenneVal, '%.2f')];
        ['Écart-type : ' num2str(ecartType, '%.2f')];
        ['Min        : ' num2str(valMin)];
        ['Max        : ' num2str(valMax)];
        ['Médiane    : ' num2str(medianeVal)];
    };
    app.StatistiquedelimageTextArea.Value = stats;

   
        end

        % Value changed function: SeuilEditField
        function SeuilEditFieldValueChanged(app, event)
     
    value = round(app.SeuilEditField.Value);  
    
    if value < 0
        value = 0;
    elseif value > 255
        value = 255;
    end

    app.seuilValue = value;

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Histogramme d''image')
            xlabel(app.UIAxes, 'Intensité')
            ylabel(app.UIAxes, 'Nombre de pixels')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ChargerImageButtonPushed, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'image_rapport', '2.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'background.jpg');
            app.SlectionnezvotrefiltreDropDownLabel = uilabel(app.UIFigure);
            app.SlectionnezvotrefiltreDropDownLabel.Text = 'Sélectionnez votre filtre :';
            app.SlectionnezvotrefiltreDropDown = uidropdown(app.UIFigure);
            app.SlectionnezvotrefiltreDropDown.Items = {'Gamma correction', 'Transformation exponentielle', 'Transformation logarithmique', 'Étirement linéaire', 'Égalisation d''histogramme', 'Filtre Sobel', 'Méthode OTSU'};
            app.SlectionnezvotrefiltreDropDown.Value = 'Gamma correction';
            app.AppliquerButton = uibutton(app.UIFigure, 'push');
            app.AppliquerButton.ButtonPushedFcn = createCallbackFcn(app, @AppliquerButtonPushed, true);
            app.AppliquerButton.Text = 'Appliquer';
            app.imageoriginaleLabel = uilabel(app.UIFigure);
            app.imageoriginaleLabel.Text = 'image originale';
            app.imagetransformeLabel = uilabel(app.UIFigure);
            app.imagetransformeLabel.Text = 'image transformée';
            app.StatistiquedelimageTextAreaLabel = uilabel(app.UIFigure);
            app.StatistiquedelimageTextAreaLabel.Text = 'Statistique de l''image';
            app.StatistiquedelimageTextArea = uitextarea(app.UIFigure);
            app.SeuilEditFieldLabel = uilabel(app.UIFigure);
            app.SeuilEditFieldLabel.Text = 'Seuil';
            app.SeuilEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeuilEditField.ValueChangedFcn = createCallbackFcn(app, @SeuilEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end